classdef ImageReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CTImageReconstructionPlatformUIFigure  matlab.ui.Figure
        ImageConfigurationPanel         matlab.ui.container.Panel
        XResoEditFieldLabel             matlab.ui.control.Label
        XResoEditField                  matlab.ui.control.NumericEditField
        YResoEditFieldLabel             matlab.ui.control.Label
        YResoEditField                  matlab.ui.control.NumericEditField
        ZResoEditFieldLabel             matlab.ui.control.Label
        ZResoEditField                  matlab.ui.control.NumericEditField
        XpixelsizeEditFieldLabel        matlab.ui.control.Label
        XpixelsizeEditField             matlab.ui.control.NumericEditField
        mmLabel                         matlab.ui.control.Label
        YpixelsizeEditFieldLabel        matlab.ui.control.Label
        YpixelsizeEditField             matlab.ui.control.NumericEditField
        mmLabel_2                       matlab.ui.control.Label
        ZpixelsizeEditFieldLabel        matlab.ui.control.Label
        ZpixelsizeEditField             matlab.ui.control.NumericEditField
        mmLabel_3                       matlab.ui.control.Label
        XCenterIndexEditFieldLabel      matlab.ui.control.Label
        XCenterIndexEditField           matlab.ui.control.NumericEditField
        YCenterIndexEditFieldLabel      matlab.ui.control.Label
        YCenterIndexEditField           matlab.ui.control.NumericEditField
        ZCenterIndexEditFieldLabel      matlab.ui.control.Label
        ZCenterIndexEditField           matlab.ui.control.NumericEditField
        SaveImageButton                 matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        ImgCenterXEditFieldLabel        matlab.ui.control.Label
        ImgCenterXEditField             matlab.ui.control.NumericEditField
        ImgCenterYEditFieldLabel        matlab.ui.control.Label
        ImgCenterYEditField             matlab.ui.control.NumericEditField
        ImgCenterZEditFieldLabel        matlab.ui.control.Label
        ImgCenterZEditField             matlab.ui.control.NumericEditField
        ObjectRadiusEditFieldLabel      matlab.ui.control.Label
        ObjectRadiusEditField           matlab.ui.control.NumericEditField
        mmLabel_10                      matlab.ui.control.Label
        mmLabel_11                      matlab.ui.control.Label
        mmLabel_12                      matlab.ui.control.Label
        mmLabel_13                      matlab.ui.control.Label
        GeometryConfigurationPanel      matlab.ui.container.Panel
        DetectorResoUdirectionEditFieldLabel  matlab.ui.control.Label
        DetectorResoUdirectionEditField  matlab.ui.control.NumericEditField
        DetectorResoVdirectionEditFieldLabel  matlab.ui.control.Label
        DetectorResoVdirectionEditField  matlab.ui.control.NumericEditField
        DetectorpixelsizeUEditFieldLabel  matlab.ui.control.Label
        DetectorpixelsizeUEditField     matlab.ui.control.NumericEditField
        mmLabel_6                       matlab.ui.control.Label
        DetectorpixelsizeVEditFieldLabel  matlab.ui.control.Label
        DetectorpixelsizeVEditField     matlab.ui.control.NumericEditField
        mmLabel_7                       matlab.ui.control.Label
        DetectorCenterIndexUEditFieldLabel  matlab.ui.control.Label
        DetectorCenterIndexUEditField   matlab.ui.control.NumericEditField
        DetectorCenterIndexVEditFieldLabel  matlab.ui.control.Label
        DetectorCenterIndexVEditField   matlab.ui.control.NumericEditField
        DetectorShapeDropDownLabel      matlab.ui.control.Label
        DetectorShapeDropDown           matlab.ui.control.DropDown
        LoadProjButton                  matlab.ui.control.Button
        SaveProjButton                  matlab.ui.control.Button
        GenerateProjButton              matlab.ui.control.Button
        mmturnLabel                     matlab.ui.control.Label
        StartsourceangleEditFieldLabel  matlab.ui.control.Label
        StartsourceangleEditField       matlab.ui.control.NumericEditField
        StartsourceZpositionEditFieldLabel  matlab.ui.control.Label
        StartsourceZpositionEditField   matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        TotalProjectionNumberEditFieldLabel  matlab.ui.control.Label
        TotalProjectionNumberEditField  matlab.ui.control.NumericEditField
        ProjectionNumberPerTurnEditFieldLabel  matlab.ui.control.Label
        ProjectionNumberPerTurnEditField  matlab.ui.control.NumericEditField
        mmLabel_8                       matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        SourcetocenterdistanceEditFieldLabel  matlab.ui.control.Label
        SourcetocenterdistanceEditField  matlab.ui.control.NumericEditField
        SourcetodetectordistanceEditFieldLabel  matlab.ui.control.Label
        SourcetodetectordistanceEditField  matlab.ui.control.NumericEditField
        HardwareDropDownLabel           matlab.ui.control.Label
        HardwareDropDown                matlab.ui.control.DropDown
        UIAxesProjectionSagittal        matlab.ui.control.UIAxes
        UIAxesProjectionCoronal         matlab.ui.control.UIAxes
        UIAxesProjectionTraversal       matlab.ui.control.UIAxes
        UIAxesImageTraversal            matlab.ui.control.UIAxes
        UIAxesImageSagittal             matlab.ui.control.UIAxes
        UIAxesImageCoronal              matlab.ui.control.UIAxes
        TSliderLabel                    matlab.ui.control.Label
        TSliderProjTrans                matlab.ui.control.Slider
        SLabel                          matlab.ui.control.Label
        TSliderProjSagit                matlab.ui.control.Slider
        CLabel                          matlab.ui.control.Label
        TSliderProjCoron                matlab.ui.control.Slider
        TSlider_4Label                  matlab.ui.control.Label
        TSliderImagTrans                matlab.ui.control.Slider
        SLabel_2                        matlab.ui.control.Label
        TSliderImagSagit                matlab.ui.control.Slider
        CLabel_2                        matlab.ui.control.Label
        TSliderImagCoron                matlab.ui.control.Slider
        ReconMethodDropDownLabel        matlab.ui.control.Label
        ReconMethodDropDown             matlab.ui.control.DropDown
        OrderedSubsetEditFieldLabel     matlab.ui.control.Label
        OrderedSubsetEditField          matlab.ui.control.NumericEditField
        MaximumIterEditFieldLabel       matlab.ui.control.Label
        MaximumIterEditField            matlab.ui.control.NumericEditField
        StartReconstructButton          matlab.ui.control.Button
        UIAxesScanTrajectory            matlab.ui.control.UIAxes
        ThisMachineContainsGPU          matlab.ui.control.Label
        DModeSwitchLabel                matlab.ui.control.Label
        TwoDimModeSwitch                matlab.ui.control.Switch
    end


    properties (Access = public)
        projectionData ProjectionData % Projection data
        reconImage     ReconImage     % Reconstructed image
        scanGeometry   ScanGeometry   % Geometry
        
    end

    methods (Access = private)
        function setProjectionDataFields(app)
            app.SourcetocenterdistanceEditField.Value = app.projectionData.getSourceToCenterDistance();
            app.SourcetodetectordistanceEditField.Value = app.projectionData.getSourceToDetectorDistance();
            app.DetectorResoUdirectionEditField.Value = app.projectionData.getDetResoU();
            app.DetectorResoVdirectionEditField.Value = app.projectionData.getDetResoV();
            app.DetectorpixelsizeUEditField.Value = app.projectionData.getDetSizeU();
            app.DetectorpixelsizeVEditField.Value = app.projectionData.getDetSizeV();
            app.DetectorCenterIndexUEditField.Value = app.projectionData.getDetCntIdxU();
            app.DetectorCenterIndexVEditField.Value = app.projectionData.getDetCntIdxV();
            if app.projectionData.getIsArcDetector()
                app.DetectorShapeDropDown.Value = 'Arc';
            else
                app.DetectorShapeDropDown.Value = 'Panel';
            end
            app.StartsourceangleEditField.Value = app.projectionData.getStartSourceAngle();
            app.StartsourceZpositionEditField.Value = app.projectionData.getStartZPos();
            app.PitchEditField.Value = app.projectionData.getPitch();
            app.TotalProjectionNumberEditField.Value = app.projectionData.getTotalProjectionNumber();
            app.ProjectionNumberPerTurnEditField.Value = app.projectionData.getProjectionNumberPerTurn();
        end
    
        function setDefaultProjectionFields(app)
            app.SourcetocenterdistanceEditField.Value = app.projectionData.getSourceToCenterDistance();
            app.SourcetodetectordistanceEditField.Value = app.projectionData.getSourceToDetectorDistance();
            app.DetectorResoUdirectionEditField.Value = app.projectionData.getDetResoU();
            app.DetectorResoVdirectionEditField.Value = app.projectionData.getDetResoV();
            app.DetectorpixelsizeUEditField.Value = app.projectionData.getDetSizeU();
            app.DetectorpixelsizeVEditField.Value = app.projectionData.getDetSizeV();
            app.DetectorCenterIndexUEditField.Value = app.projectionData.getDetCntIdxU();
            app.DetectorCenterIndexVEditField.Value = app.projectionData.getDetCntIdxV();
            if app.projectionData.getIsArcDetector()
                app.DetectorShapeDropDown.Value = 'Arc';
            else
                app.DetectorShapeDropDown.Value = 'Panel';
            end
            app.StartsourceangleEditField.Value = app.projectionData.getStartSourceAngle();
            app.StartsourceZpositionEditField.Value = app.projectionData.getStartZPos();
            app.PitchEditField.Value = app.projectionData.getPitch();
            app.TotalProjectionNumberEditField.Value = app.projectionData.getTotalProjectionNumber();
            app.ProjectionNumberPerTurnEditField.Value = app.projectionData.getProjectionNumberPerTurn();
        end
    
        function setReconImageFields(app)
            app.XResoEditField.Value = app.reconImage.getResoX();
            app.YResoEditField.Value = app.reconImage.getResoY();
            app.ZResoEditField.Value = app.reconImage.getResoZ();
            app.XpixelsizeEditField.Value = app.reconImage.getPixelSizeX();
            app.YpixelsizeEditField.Value = app.reconImage.getPixelSizeY();
            app.ZpixelsizeEditField.Value = app.reconImage.getPixelSizeZ();
            app.XCenterIndexEditField.Value = app.reconImage.getCtrIdxX();
            app.YCenterIndexEditField.Value = app.reconImage.getCtrIdxY();
            app.ZCenterIndexEditField.Value = app.reconImage.getCtrIdxZ();
            app.ImgCenterXEditField.Value = app.reconImage.getImgCenterX();
            app.ImgCenterYEditField.Value = app.reconImage.getImgCenterY();
            app.ImgCenterZEditField.Value = app.reconImage.getImgCenterZ();
        end
    
        function setDefaultReconImageFields(app)
            app.XResoEditField.Value = app.reconImage.getResoX();
            app.YResoEditField.Value = app.reconImage.getResoY();
            app.ZResoEditField.Value = app.reconImage.getResoZ();
            app.XpixelsizeEditField.Value = app.reconImage.getPixelSizeX();
            app.YpixelsizeEditField.Value = app.reconImage.getPixelSizeY();
            app.ZpixelsizeEditField.Value = app.reconImage.getPixelSizeZ();
            app.XCenterIndexEditField.Value = app.reconImage.getCtrIdxX();
            app.YCenterIndexEditField.Value = app.reconImage.getCtrIdxY();
            app.ZCenterIndexEditField.Value = app.reconImage.getCtrIdxZ();
            app.ImgCenterXEditField.Value = app.reconImage.getImgCenterX();
            app.ImgCenterYEditField.Value = app.reconImage.getImgCenterY();
            app.ImgCenterZEditField.Value = app.reconImage.getImgCenterZ();
        end

        function drawProjectionData(app)
            proj = app.projectionData.getProjection();
            [YYY, ZZZ, PPP] = size(proj);
            imagesc(squeeze(proj(:,:,floor(PPP/2))), 'Parent', app.UIAxesProjectionTraversal);
            imagesc(squeeze(proj(:,floor(ZZZ/2),:)), 'Parent', app.UIAxesProjectionSagittal);
            imagesc(squeeze(proj(floor(YYY/2),:,:)), 'Parent', app.UIAxesProjectionCoronal);
            
            app.TSliderProjTrans.Visible = true;
            app.TSliderProjTrans.Enable = true;
            app.TSliderProjTrans.Limits = [1, PPP];
            
            app.TSliderProjSagit.Visible = true;
            app.TSliderProjSagit.Enable = true;
            app.TSliderProjSagit.Limits = [1, ZZZ];
            
            app.TSliderProjCoron.Visible = true;
            app.TSliderProjCoron.Enable = true;
            app.TSliderProjCoron.Limits = [1, YYY];
        end
    
        function drawReconImage(app)
            img = app.reconImage.getImage();
            [XXX, YYY, ZZZ] = size(img);
            imagesc(squeeze(img(:,:,floor(ZZZ/2))), 'Parent', app.UIAxesImageTraversal);
            imagesc(squeeze(img(:,floor(YYY/2),:)), 'Parent', app.UIAxesImageSagittal);
            imagesc(squeeze(img(floor(XXX/2),:,:)), 'Parent', app.UIAxesImageCoronal);
            
            app.TSliderImagTrans.Visible = true;
            app.TSliderImagTrans.Enable = true;
            app.TSliderImagTrans.Limits = [1, ZZZ];
            
            app.TSliderImagSagit.Visible = true;
            app.TSliderImagSagit.Enable = true;
            app.TSliderImagSagit.Limits = [1, YYY];
            
            app.TSliderImagCoron.Visible = true;
            app.TSliderImagCoron.Enable = true;
            app.TSliderImagCoron.Limits = [1, XXX];
        end
        
        function switchAllWidgets(app, status)
            set(app.SourcetocenterdistanceEditField, 'Enable', status);
            set(app.SourcetodetectordistanceEditField, 'Enable', status);
            set(app.DetectorResoUdirectionEditField, 'Enable', status);
            set(app.DetectorResoVdirectionEditField, 'Enable', status);
            set(app.DetectorpixelsizeUEditField,  'Enable', status);
            set(app.DetectorpixelsizeVEditField,  'Enable', status);
            set(app.DetectorCenterIndexUEditField, 'Enable', status);
            set(app.DetectorCenterIndexVEditField, 'Enable', status);
            set(app.DetectorShapeDropDown, 'Enable', status);
            set(app.StartsourceangleEditField, 'Enable', status);
            set(app.StartsourceZpositionEditField,  'Enable', status);
            set(app.PitchEditField,  'Enable', status);
            set(app.TotalProjectionNumberEditField, 'Enable', status);
            set(app.ProjectionNumberPerTurnEditField,  'Enable', status);
            set(app.LoadProjButton,  'Enable', status);
            set(app.SaveProjButton,  'Enable', status);
            set(app.GenerateProjButton,  'Enable', status);
            set(app.XResoEditField,  'Enable', status);
            set(app.YResoEditField,  'Enable', status);
            set(app.ZResoEditField,  'Enable', status);
            set(app.XpixelsizeEditField,  'Enable', status);
            set(app.YpixelsizeEditField,  'Enable', status);
            set(app.ZpixelsizeEditField,  'Enable', status);
            set(app.XCenterIndexEditField,  'Enable', status);
            set(app.YCenterIndexEditField,  'Enable', status);
            set(app.ZCenterIndexEditField, 'Enable', status);
            set(app.ImgCenterXEditField,  'Enable', status);
            set(app.ImgCenterYEditField,  'Enable', status);
            set(app.ImgCenterZEditField,  'Enable', status);
            set(app.LoadImageButton,  'Enable', status);
            set(app.SaveImageButton,  'Enable', status);
            set(app.HardwareDropDown,  'Enable', status);
            set(app.ReconMethodDropDown,  'Enable', status);
            set(app.OrderedSubsetEditField,  'Enable', status);
            set(app.MaximumIterEditField,  'Enable', status);
            set(app.StartReconstructButton,  'Enable', status);
            set(app.TSliderProjTrans, 'Enable', status);
            set(app.TSliderProjSagit, 'Enable', status);
            set(app.TSliderProjCoron, 'Enable', status);
            set(app.TSliderImagTrans, 'Enable', status);
            set(app.TSliderImagSagit, 'Enable', status);
            set(app.TSliderImagCoron, 'Enable', status);            
        end
        
        function drawTrajectory(app)
            z = app.projectionData.getZShifts() + app.projectionData.getStartZPos();
            ang = app.projectionData.getViewAngles();
            x = cos(ang + app.projectionData.getStartSourceAngle()) * app.projectionData.getSourceToCenterDistance();
            y = sin(ang + app.projectionData.getStartSourceAngle()) * app.projectionData.getSourceToCenterDistance();
            plot3(x,y,z, '*', 'Parent', app.UIAxesScanTrajectory);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxesImageCoronal,'off');
            axis(app.UIAxesImageSagittal,'off');
            axis(app.UIAxesImageTraversal,'off');
            axis(app.UIAxesProjectionCoronal, 'off');
            axis(app.UIAxesProjectionSagittal, 'off');
            axis(app.UIAxesProjectionTraversal, 'off');
            
            app.projectionData = ProjectionData;
            app.reconImage = ReconImage;
            app.scanGeometry = ScanGeometry;
            app.setDefaultProjectionFields();
            app.setDefaultReconImageFields();
            app.drawTrajectory();
            rotate3d(app.UIAxesScanTrajectory, 'on');
            app.UIAxesScanTrajectory.XAxisLocation = 'origin';
            app.UIAxesScanTrajectory.YAxisLocation = 'origin';
            
%             gpus = gpuDeviceCount;
%             count = 0;
%             for ii = 1 : gpus
%                 g = gpuDevice(ii);
%                 if(g.ComputeCapability > 3.0)
%                     count = count + 1;
%                 end
%             end
%             if(count > 0)
%                 if(count == 1)
%                     app.HardwareDropDown.Items = {'GPU', 'CPU', 'CPUs'};
%                 end
%                 app.ThisMachineContainsGPU.Text = ['This host contains ' num2str(count), ' GPUs with Compute Capability equal or larger than 3.0'];
%             else
%                 app.HardwareDropDown.Items = {'CPU', 'CPUs'};
%                 app.ThisMachineContainsGPU.Text = ['This host does not contains available GPU, GPU computing will not be supported!\n'];
%             end
            
        end

        % Value changed function: SourcetocenterdistanceEditField
        function SourcetocenterdistanceEditFieldValueChanged(app, event)
            value = app.SourcetocenterdistanceEditField.Value;
            app.projectionData.setSourceToCenterDistance(value);
            app.drawTrajectory();
        end

        % Value changed function: SourcetodetectordistanceEditField
        function SourcetodetectordistanceEditFieldValueChanged(app, event)
            value = app.SourcetodetectordistanceEditField.Value;
            app.projectionData.setSourceToDetectorDistance(value);
        end

        % Value changed function: DetectorResoUdirectionEditField
        function DetectorResoUdirectionEditFieldValueChanged(app, event)
            value = app.DetectorResoUdirectionEditField.Value;
            app.projectionData.setDetResoU(value);
        end

        % Value changed function: DetectorResoVdirectionEditField
        function DetectorResoVdirectionEditFieldValueChanged(app, event)
            value = app.DetectorResoVdirectionEditField.Value;
            app.projectionData.setDetResoV(value);
        end

        % Value changed function: DetectorpixelsizeUEditField
        function DetectorpixelsizeUEditFieldValueChanged(app, event)
            value = app.DetectorpixelsizeUEditField.Value;
            app.projectionData.setDetSizeU(value);
        end

        % Value changed function: DetectorpixelsizeVEditField
        function DetectorpixelsizeVEditFieldValueChanged(app, event)
            value = app.DetectorpixelsizeVEditField.Value;
            app.projectionData.setDetResoV(value);
        end

        % Value changed function: DetectorCenterIndexUEditField
        function DetectorCenterIndexUEditFieldValueChanged(app, event)
            value = app.DetectorCenterIndexUEditField.Value;
            app.projectionData.setDetCntIdxU(value);
        end

        % Value changed function: DetectorCenterIndexVEditField
        function DetectorCenterIndexVEditFieldValueChanged(app, event)
            value = app.DetectorCenterIndexVEditField.Value;
            app.projectionData.setDetCntIdxV(value);
        end

        % Value changed function: DetectorShapeDropDown
        function DetectorShapeDropDownValueChanged(app, event)
            value = app.DetectorShapeDropDown.Value;
            if strcmp(value, 'Arc')
                app.projectionData.setIsArcDetector(true);
            else
                app.projectionData.setIsArcDetector(false);
            end
        end

        % Value changed function: StartsourceangleEditField
        function StartsourceangleEditFieldValueChanged(app, event)
            value = app.StartsourceangleEditField.Value;
            app.projectionData.setStartSourceAngle(value);
            app.drawTrajectory();
        end

        % Value changed function: StartsourceZpositionEditField
        function StartsourceZpositionEditFieldValueChanged(app, event)
            value = app.StartsourceZpositionEditField.Value;
            app.projectionData.setStartZPos(value);
            app.drawTrajectory();
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;
            app.projectionData.setPitch(value);
            app.drawTrajectory();
        end

        % Value changed function: TotalProjectionNumberEditField
        function TotalProjectionNumberEditFieldValueChanged(app, event)
            value = app.TotalProjectionNumberEditField.Value;
            app.projectionData.setTotalProjectionNumber(value);
            app.drawTrajectory();
        end

        % Value changed function: ProjectionNumberPerTurnEditField
        function ProjectionNumberPerTurnEditFieldValueChanged(app, event)
            value = app.ProjectionNumberPerTurnEditField.Value;
            app.projectionData.setProjectionNumPerTurn(value);
            app.drawTrajectory();
        end

        % Value changed function: XResoEditField
        function XResoEditFieldValueChanged(app, event)
            value = app.XResoEditField.Value;
            app.reconImage.setResoX(value);
            app.XCenterIndexEditField.Value = app.reconImage.getCtrIdxX();
        end

        % Value changed function: YResoEditField
        function YResoEditFieldValueChanged(app, event)
            value = app.YResoEditField.Value;
            app.reconImage.setResoY(value);
            app.YCenterIndexEditField.Value = app.reconImage.getCtrIdxY();
        end

        % Value changed function: ZResoEditField
        function ZResoEditFieldValueChanged(app, event)
            value = app.ZResoEditField.Value;
            app.reconImage.setResoZ(value);
            app.ZCenterIndexEditField.Value = app.reconImage.getCtrIdxZ();
        end

        % Value changed function: XpixelsizeEditField
        function XpixelsizeEditFieldValueChanged(app, event)
            value = app.XpixelsizeEditField.Value;
            app.reconImage.setPixelSizeX(value);
            app.XCenterIndexEditField.Value = app.reconImage.getCtrIdxX();
        end

        % Value changed function: YpixelsizeEditField
        function YpixelsizeEditFieldValueChanged(app, event)
            value = app.YpixelsizeEditField.Value;
            app.reconImage.setPixelSizeY(value);
            app.YCenterIndexEditField.Value = app.reconImage.getCtrIdxY();
        end

        % Value changed function: ZpixelsizeEditField
        function ZpixelsizeEditFieldValueChanged(app, event)
            value = app.ZpixelsizeEditField.Value;
            app.reconImage.setPixelSizeZ(value);
            app.ZCenterIndexEditField.Value = app.reconImage.getCtrIdxZ();
        end

        % Value changed function: XCenterIndexEditField
        function XCenterIndexEditFieldValueChanged(app, event)
            value = app.XCenterIndexEditField.Value;
            app.reconImage.setCtrIdxX(value);
            app.ImgCenterXEditField.Value = app.reconImage.getImgCenterX();
        end

        % Value changed function: YCenterIndexEditField
        function YCenterIndexEditFieldValueChanged(app, event)
            value = app.YCenterIndexEditField.Value;
            app.reconImage.setCtrIdxY(value);
            app.ImgCenterYEditField.Value = app.reconImage.getImgCenterY();
        end

        % Value changed function: ZCenterIndexEditField
        function ZCenterIndexEditFieldValueChanged(app, event)
            value = app.ZCenterIndexEditField.Value;
            app.reconImage.setCtrIdxZ(value);
            app.ImgCenterZEditField.Value = app.reconImage.getImgCenterZ();
        end

        % Value changed function: ImgCenterXEditField
        function ImgCenterXEditFieldValueChanged(app, event)
            value = app.ImgCenterXEditField.Value;
            app.reconImage.setImgCenterX(value);
            app.XCenterIndexEditField.Value = app.reconImage.getCtrIdxX();
        end

        % Value changed function: ImgCenterYEditField
        function ImgCenterYEditFieldValueChanged(app, event)
            value = app.ImgCenterYEditField.Value;
            app.reconImage.setImgCenterY(value);
            app.YCenterIndexEditField.Value = app.reconImage.getCtrIdxY();
        end

        % Value changed function: ImgCenterZEditField
        function ImgCenterZEditFieldValueChanged(app, event)
            value = app.ImgCenterZEditField.Value;
            app.reconImage.setImgCenterZ(value);
            app.ZCenterIndexEditField.Value = app.reconImage.getCtrIdxZ();
        end

        % Button pushed function: LoadProjButton
        function LoadProjButtonPushed(app, event)
            filter = {'*.projData'};
            [file, path] = uigetfile(filter);
            fileName = [path, file];
            if ~(isequal(file,0) || isequal(path,0))
                app.projectionData.ReadFile(fileName);
                app.setProjectionDataFields();                            
                msgbox('Projection Data loaded', 'Projection loaded');           
                app.drawProjectionData();
            end     
            app.drawTrajectory();
        end

        % Button pushed function: SaveProjButton
        function SaveProjButtonPushed(app, event)
            filter = {'*.projData'};
            [file, path] = uiputfile(filter);
            fileName = [path, file];
            if ~(isequal(file,0) || isequal(path,0))
                app.projectionData.WriteFile(fileName);
                msgbox('Projection Data saved', 'Projection saved');           
            end              
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            filter = {'*.reconImg'};
            [file, path] = uigetfile(filter);
            fileName = [path, file];
            if ~(isequal(file,0) || isequal(path,0))
                app.reconImage.ReadFile(fileName);
                app.setReconImageFields();                            
                msgbox('Reconstructed Image loaded', 'Image loaded');           
                app.drawReconImage();
            end         
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            filter = {'*.reconImg'};
            [file, path] = uiputfile(filter);
            fileName = [path, file];
            if ~(isequal(file,0) || isequal(path,0))
                app.reconImage.WriteFile(fileName);
                msgbox('Reconstructed Image saved', 'Image saved');           
            end  
        end

        % Value changing function: TSliderProjTrans
        function TSliderProjTransValueChanging(app, event)
            changingValue = event.Value;
            Proj = app.projectionData.getProjection();
            imagesc(squeeze(Proj(:,:, floor(changingValue))), 'Parent', app.UIAxesProjectionTraversal);
        end

        % Value changing function: TSliderProjSagit
        function TSliderProjSagitValueChanging(app, event)
            changingValue = event.Value;
            Proj = app.projectionData.getProjection();
            imagesc(squeeze(Proj(:,floor(changingValue), :)), 'Parent', app.UIAxesProjectionSagittal);
        end

        % Value changing function: TSliderProjCoron
        function TSliderProjCoronValueChanging(app, event)
            changingValue = event.Value;
            Proj = app.projectionData.getProjection();
            imagesc(squeeze(Proj(floor(changingValue),:, :)), 'Parent', app.UIAxesProjectionCoronal);
        end

        % Value changing function: TSliderImagTrans
        function TSliderImagTransValueChanging(app, event)
            changingValue = event.Value;
            img = app.reconImage.getImage();
            imagesc(squeeze(img(:, :, floor(changingValue))), 'Parent', app.UIAxesImageTraversal);
        end

        % Value changing function: TSliderImagSagit
        function TSliderImagSagitValueChanging(app, event)
            changingValue = event.Value;
            img = app.reconImage.getImage();
            imagesc(squeeze(img(:, floor(changingValue), :)), 'Parent', app.UIAxesImageSagittal);
        end

        % Value changing function: TSliderImagCoron
        function TSliderImagCoronValueChanging(app, event)
            changingValue = event.Value;
            img = app.reconImage.getImage();
            imagesc(squeeze(img(floor(changingValue), :, :)), 'Parent', app.UIAxesImageCoronal);
        end

        % Button pushed function: StartReconstructButton
        function StartReconstructButtonPushed(app, event)
            app.switchAllWidgets('off');
            if strcmp(app.HardwareDropDown.Value, 'GPUs')
                isMultiGPU = true;
            else
                isMultiGPU = false;
            end
            if strcmp(app.ReconMethodDropDown.Value, 'OS-SART')
                osNum = app.OrderedSubsetEditField.Value;
                iterNum = app.MaximumIterEditField.Value;
                app.reconImage = ossartGUI('Branchless', app.projectionData, app.reconImage, osNum, iterNum, isMultiGPU, app);
            elseif strcmp(app.ReconMethodDropDown.Value, 'Conjugate Gradient')
                iterNum = app.MaximumIterEditField.Value;
                app.reconImage = conjugateGradientGUI('Branchless', app.projectionData, app.reconImage, iterNum, isMultiGPU, app);
            end
            app.switchAllWidgets('on');
        end

        % Value changed function: TwoDimModeSwitch
        function TwoDimModeSwitchValueChanged(app, event)
            value = app.TwoDimModeSwitch.Value;
            if strcmp(value, 'On')
                status = 'off';
                set(app.DetectorResoVdirectionEditField, 'Enable', status);
                set(app.DetectorpixelsizeVEditField,  'Enable', status);
                set(app.DetectorCenterIndexVEditField, 'Enable', status);
                set(app.StartsourceZpositionEditField,  'Enable', status);
                set(app.PitchEditField,  'Enable', status);
                set(app.ZResoEditField,  'Enable', status);
                set(app.ZpixelsizeEditField,  'Enable', status);
                set(app.ZCenterIndexEditField, 'Enable', status);
                set(app.ImgCenterZEditField,  'Enable', status);
                set(app.TSliderProjSagit, 'Enable', status);
                set(app.TSliderProjCoron, 'Enable', status);
                set(app.TSliderImagSagit, 'Enable', status);
                set(app.TSliderImagCoron, 'Enable', status);
            else
                status = 'on';
                set(app.DetectorResoVdirectionEditField, 'Enable', status);
                set(app.DetectorpixelsizeVEditField,  'Enable', status);
                set(app.DetectorCenterIndexVEditField, 'Enable', status);
                set(app.StartsourceZpositionEditField,  'Enable', status);
                set(app.PitchEditField,  'Enable', status);
                set(app.ZResoEditField,  'Enable', status);
                set(app.ZpixelsizeEditField,  'Enable', status);
                set(app.ZCenterIndexEditField, 'Enable', status);
                set(app.ImgCenterZEditField,  'Enable', status);
                set(app.TSliderProjSagit, 'Enable', status);
                set(app.TSliderProjCoron, 'Enable', status);
                set(app.TSliderImagSagit, 'Enable', status);
                set(app.TSliderImagCoron, 'Enable', status);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CTImageReconstructionPlatformUIFigure = uifigure('Visible', 'off');
            app.CTImageReconstructionPlatformUIFigure.Name = 'CT Image Reconstruction Platform';
            app.ImageConfigurationPanel = uipanel(app.CTImageReconstructionPlatformUIFigure);
            app.ImageConfigurationPanel.Title = 'Image Configuration';
            app.XResoEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.XResoEditFieldLabel.Text = 'X Reso';
            app.XResoEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.XResoEditField.Limits = [1 Inf];
            app.XResoEditField.ValueDisplayFormat = '%.0f';
            app.XResoEditField.ValueChangedFcn = createCallbackFcn(app, @XResoEditFieldValueChanged, true);
            app.XResoEditField.Value = 512;
            app.YResoEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.YResoEditFieldLabel.Text = 'Y Reso';
            app.YResoEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.YResoEditField.Limits = [1 Inf];
            app.YResoEditField.ValueDisplayFormat = '%.0f';
            app.YResoEditField.ValueChangedFcn = createCallbackFcn(app, @YResoEditFieldValueChanged, true);
            app.YResoEditField.Value = 512;
            app.ZResoEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.ZResoEditFieldLabel.Text = 'Z Reso';
            app.ZResoEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.ZResoEditField.Limits = [1 Inf];
            app.ZResoEditField.ValueDisplayFormat = '%.0f';
            app.ZResoEditField.ValueChangedFcn = createCallbackFcn(app, @ZResoEditFieldValueChanged, true);
            app.ZResoEditField.Value = 512;
            app.XpixelsizeEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.XpixelsizeEditFieldLabel.Text = 'X pixel size';
            app.XpixelsizeEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.XpixelsizeEditField.Limits = [0 Inf];
            app.XpixelsizeEditField.ValueDisplayFormat = '%.5f';
            app.XpixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @XpixelsizeEditFieldValueChanged, true);
            app.XpixelsizeEditField.Value = 0.9766;
            app.mmLabel = uilabel(app.ImageConfigurationPanel);
            app.mmLabel.Text = 'mm';
            app.YpixelsizeEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.YpixelsizeEditFieldLabel.Text = 'Y pixel size';
            app.YpixelsizeEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.YpixelsizeEditField.Limits = [0 Inf];
            app.YpixelsizeEditField.ValueDisplayFormat = '%.5f';
            app.YpixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @YpixelsizeEditFieldValueChanged, true);
            app.YpixelsizeEditField.Value = 0.9766;
            app.mmLabel_2 = uilabel(app.ImageConfigurationPanel);
            app.mmLabel_2.Text = 'mm';
            app.ZpixelsizeEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.ZpixelsizeEditFieldLabel.Text = 'Z pixel size';
            app.ZpixelsizeEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.ZpixelsizeEditField.Limits = [0 Inf];
            app.ZpixelsizeEditField.ValueDisplayFormat = '%.5f';
            app.ZpixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ZpixelsizeEditFieldValueChanged, true);
            app.ZpixelsizeEditField.Value = 0.9766;
            app.mmLabel_3 = uilabel(app.ImageConfigurationPanel);
            app.mmLabel_3.Text = 'mm';
            app.XCenterIndexEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.XCenterIndexEditFieldLabel.Text = 'X Center Index ';
            app.XCenterIndexEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.XCenterIndexEditField.Limits = [0 Inf];
            app.XCenterIndexEditField.ValueDisplayFormat = '%.5f';
            app.XCenterIndexEditField.ValueChangedFcn = createCallbackFcn(app, @XCenterIndexEditFieldValueChanged, true);
            app.XCenterIndexEditField.Value = 255.5;
            app.YCenterIndexEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.YCenterIndexEditFieldLabel.Text = 'Y Center Index ';
            app.YCenterIndexEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.YCenterIndexEditField.Limits = [0 Inf];
            app.YCenterIndexEditField.ValueDisplayFormat = '%.5f';
            app.YCenterIndexEditField.ValueChangedFcn = createCallbackFcn(app, @YCenterIndexEditFieldValueChanged, true);
            app.YCenterIndexEditField.Value = 255.5;
            app.ZCenterIndexEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.ZCenterIndexEditFieldLabel.Text = 'Z Center Index ';
            app.ZCenterIndexEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.ZCenterIndexEditField.Limits = [0 Inf];
            app.ZCenterIndexEditField.ValueDisplayFormat = '%.5f';
            app.ZCenterIndexEditField.ValueChangedFcn = createCallbackFcn(app, @ZCenterIndexEditFieldValueChanged, true);
            app.ZCenterIndexEditField.Value = 255.5;
            app.SaveImageButton = uibutton(app.ImageConfigurationPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.LoadImageButton = uibutton(app.ImageConfigurationPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ImgCenterXEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.ImgCenterXEditFieldLabel.Text = 'Img Center X';
            app.ImgCenterXEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.ImgCenterXEditField.ValueDisplayFormat = '%.5f';
            app.ImgCenterXEditField.ValueChangedFcn = createCallbackFcn(app, @ImgCenterXEditFieldValueChanged, true);
            app.ImgCenterYEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.ImgCenterYEditFieldLabel.Text = 'Img Center Y';
            app.ImgCenterYEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.ImgCenterYEditField.ValueDisplayFormat = '%.5f';
            app.ImgCenterYEditField.ValueChangedFcn = createCallbackFcn(app, @ImgCenterYEditFieldValueChanged, true);
            app.ImgCenterZEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.ImgCenterZEditFieldLabel.Text = 'Img Center Z';
            app.ImgCenterZEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.ImgCenterZEditField.ValueDisplayFormat = '%.5f';
            app.ImgCenterZEditField.ValueChangedFcn = createCallbackFcn(app, @ImgCenterZEditFieldValueChanged, true);
            app.ObjectRadiusEditFieldLabel = uilabel(app.ImageConfigurationPanel);
            app.ObjectRadiusEditFieldLabel.Enable = 'off';
            app.ObjectRadiusEditFieldLabel.Text = 'Object Radius';
            app.ObjectRadiusEditField = uieditfield(app.ImageConfigurationPanel, 'numeric');
            app.ObjectRadiusEditField.Limits = [0 Inf];
            app.ObjectRadiusEditField.ValueDisplayFormat = '%.5f';
            app.ObjectRadiusEditField.Editable = 'off';
            app.ObjectRadiusEditField.Enable = 'off';
            app.ObjectRadiusEditField.Value = 250;
            app.mmLabel_10 = uilabel(app.ImageConfigurationPanel);
            app.mmLabel_10.Text = 'mm';
            app.mmLabel_11 = uilabel(app.ImageConfigurationPanel);
            app.mmLabel_11.Text = 'mm';
            app.mmLabel_12 = uilabel(app.ImageConfigurationPanel);
            app.mmLabel_12.Text = 'mm';
            app.mmLabel_13 = uilabel(app.ImageConfigurationPanel);
            app.mmLabel_13.Text = 'mm';
            app.GeometryConfigurationPanel = uipanel(app.CTImageReconstructionPlatformUIFigure);
            app.GeometryConfigurationPanel.Title = 'Geometry Configuration';
            app.DetectorResoUdirectionEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.DetectorResoUdirectionEditFieldLabel.Text = 'Detector Reso U direction';
            app.DetectorResoUdirectionEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.DetectorResoUdirectionEditField.Limits = [1 Inf];
            app.DetectorResoUdirectionEditField.ValueDisplayFormat = '%.0f';
            app.DetectorResoUdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorResoUdirectionEditFieldValueChanged, true);
            app.DetectorResoUdirectionEditField.Value = 888;
            app.DetectorResoVdirectionEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.DetectorResoVdirectionEditFieldLabel.Text = 'Detector Reso V direction';
            app.DetectorResoVdirectionEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.DetectorResoVdirectionEditField.Limits = [1 Inf];
            app.DetectorResoVdirectionEditField.ValueDisplayFormat = '%.0f';
            app.DetectorResoVdirectionEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorResoVdirectionEditFieldValueChanged, true);
            app.DetectorResoVdirectionEditField.Value = 64;
            app.DetectorpixelsizeUEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.DetectorpixelsizeUEditFieldLabel.Text = 'Detector pixel size U';
            app.DetectorpixelsizeUEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.DetectorpixelsizeUEditField.Limits = [0 Inf];
            app.DetectorpixelsizeUEditField.ValueDisplayFormat = '%.5f';
            app.DetectorpixelsizeUEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorpixelsizeUEditFieldValueChanged, true);
            app.DetectorpixelsizeUEditField.Value = 1.0239;
            app.mmLabel_6 = uilabel(app.GeometryConfigurationPanel);
            app.mmLabel_6.Text = 'mm';
            app.DetectorpixelsizeVEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.DetectorpixelsizeVEditFieldLabel.Text = 'Detector pixel size V';
            app.DetectorpixelsizeVEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.DetectorpixelsizeVEditField.Limits = [0 Inf];
            app.DetectorpixelsizeVEditField.ValueDisplayFormat = '%.5f';
            app.DetectorpixelsizeVEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorpixelsizeVEditFieldValueChanged, true);
            app.DetectorpixelsizeVEditField.Value = 1.0963;
            app.mmLabel_7 = uilabel(app.GeometryConfigurationPanel);
            app.mmLabel_7.Text = 'mm';
            app.DetectorCenterIndexUEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.DetectorCenterIndexUEditFieldLabel.Text = 'Detector Center Index U';
            app.DetectorCenterIndexUEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.DetectorCenterIndexUEditField.ValueDisplayFormat = '%.5f';
            app.DetectorCenterIndexUEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorCenterIndexUEditFieldValueChanged, true);
            app.DetectorCenterIndexUEditField.Value = 443.75;
            app.DetectorCenterIndexVEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.DetectorCenterIndexVEditFieldLabel.Text = 'Detector Center Index V';
            app.DetectorCenterIndexVEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.DetectorCenterIndexVEditField.Limits = [0 Inf];
            app.DetectorCenterIndexVEditField.ValueDisplayFormat = '%.5f';
            app.DetectorCenterIndexVEditField.ValueChangedFcn = createCallbackFcn(app, @DetectorCenterIndexVEditFieldValueChanged, true);
            app.DetectorCenterIndexVEditField.Value = 31.5;
            app.DetectorShapeDropDownLabel = uilabel(app.GeometryConfigurationPanel);
            app.DetectorShapeDropDownLabel.Text = 'Detector Shape';
            app.DetectorShapeDropDown = uidropdown(app.GeometryConfigurationPanel);
            app.DetectorShapeDropDown.Items = {'Arc', 'Panel'};
            app.DetectorShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorShapeDropDownValueChanged, true);
            app.DetectorShapeDropDown.Value = 'Arc';
            app.LoadProjButton = uibutton(app.GeometryConfigurationPanel, 'push');
            app.LoadProjButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjButtonPushed, true);
            app.LoadProjButton.Text = 'Load Proj';
            app.SaveProjButton = uibutton(app.GeometryConfigurationPanel, 'push');
            app.SaveProjButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjButtonPushed, true);
            app.SaveProjButton.Text = 'Save Proj';
            app.GenerateProjButton = uibutton(app.GeometryConfigurationPanel, 'push');
            app.GenerateProjButton.Text = 'Generate Proj';
            app.mmturnLabel = uilabel(app.GeometryConfigurationPanel);
            app.mmturnLabel.Text = 'mm/turn';
            app.StartsourceangleEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.StartsourceangleEditFieldLabel.Text = 'Start source angle';
            app.StartsourceangleEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.StartsourceangleEditField.ValueChangedFcn = createCallbackFcn(app, @StartsourceangleEditFieldValueChanged, true);
            app.StartsourceZpositionEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.StartsourceZpositionEditFieldLabel.Text = 'Start source Z position';
            app.StartsourceZpositionEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.StartsourceZpositionEditField.ValueChangedFcn = createCallbackFcn(app, @StartsourceZpositionEditFieldValueChanged, true);
            app.PitchEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.TotalProjectionNumberEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.TotalProjectionNumberEditFieldLabel.Text = 'Total Projection Number';
            app.TotalProjectionNumberEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.TotalProjectionNumberEditField.Limits = [1 Inf];
            app.TotalProjectionNumberEditField.ValueDisplayFormat = '%.0f';
            app.TotalProjectionNumberEditField.ValueChangedFcn = createCallbackFcn(app, @TotalProjectionNumberEditFieldValueChanged, true);
            app.TotalProjectionNumberEditField.Value = 984;
            app.ProjectionNumberPerTurnEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.ProjectionNumberPerTurnEditFieldLabel.Text = 'Projection Number Per Turn';
            app.ProjectionNumberPerTurnEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.ProjectionNumberPerTurnEditField.Limits = [1 Inf];
            app.ProjectionNumberPerTurnEditField.ValueDisplayFormat = '%.0f';
            app.ProjectionNumberPerTurnEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectionNumberPerTurnEditFieldValueChanged, true);
            app.ProjectionNumberPerTurnEditField.Value = 984;
            app.mmLabel_8 = uilabel(app.GeometryConfigurationPanel);
            app.mmLabel_8.Text = 'mm';
            app.mmLabel_9 = uilabel(app.GeometryConfigurationPanel);
            app.mmLabel_9.Text = 'mm';
            app.SourcetocenterdistanceEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.SourcetocenterdistanceEditFieldLabel.Text = 'Source to center distance';
            app.SourcetocenterdistanceEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.SourcetocenterdistanceEditField.Limits = [0 Inf];
            app.SourcetocenterdistanceEditField.ValueChangedFcn = createCallbackFcn(app, @SourcetocenterdistanceEditFieldValueChanged, true);
            app.SourcetocenterdistanceEditField.Value = 538;
            app.SourcetodetectordistanceEditFieldLabel = uilabel(app.GeometryConfigurationPanel);
            app.SourcetodetectordistanceEditFieldLabel.Text = 'Source to detector distance';
            app.SourcetodetectordistanceEditField = uieditfield(app.GeometryConfigurationPanel, 'numeric');
            app.SourcetodetectordistanceEditField.Limits = [0 Inf];
            app.SourcetodetectordistanceEditField.ValueChangedFcn = createCallbackFcn(app, @SourcetodetectordistanceEditFieldValueChanged, true);
            app.SourcetodetectordistanceEditField.Value = 946;
            app.HardwareDropDownLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.HardwareDropDownLabel.Text = 'Hardware';
            app.HardwareDropDown = uidropdown(app.CTImageReconstructionPlatformUIFigure);
            app.HardwareDropDown.Items = {'GPU', 'GPUs', 'CPU', 'CPUs'};
            app.HardwareDropDown.Value = 'GPU';
            app.UIAxesProjectionSagittal = uiaxes(app.CTImageReconstructionPlatformUIFigure);
            xlabel(app.UIAxesProjectionSagittal, 'X')
            ylabel(app.UIAxesProjectionSagittal, 'Y')
            app.UIAxesProjectionCoronal = uiaxes(app.CTImageReconstructionPlatformUIFigure);
            xlabel(app.UIAxesProjectionCoronal, 'X')
            ylabel(app.UIAxesProjectionCoronal, 'Y')
            app.UIAxesProjectionTraversal = uiaxes(app.CTImageReconstructionPlatformUIFigure);
            xlabel(app.UIAxesProjectionTraversal, 'X')
            ylabel(app.UIAxesProjectionTraversal, 'Y')
            app.UIAxesImageTraversal = uiaxes(app.CTImageReconstructionPlatformUIFigure);
            xlabel(app.UIAxesImageTraversal, 'X')
            ylabel(app.UIAxesImageTraversal, 'Y')
            app.UIAxesImageSagittal = uiaxes(app.CTImageReconstructionPlatformUIFigure);
            xlabel(app.UIAxesImageSagittal, 'X')
            ylabel(app.UIAxesImageSagittal, 'Y')
            app.UIAxesImageCoronal = uiaxes(app.CTImageReconstructionPlatformUIFigure);
            xlabel(app.UIAxesImageCoronal, 'X')
            ylabel(app.UIAxesImageCoronal, 'Y')
            app.TSliderLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.TSliderLabel.Text = 'T';
            app.TSliderProjTrans = uislider(app.CTImageReconstructionPlatformUIFigure);
            app.TSliderProjTrans.MajorTicks = [];
            app.TSliderProjTrans.MajorTickLabels = {};
            app.TSliderProjTrans.ValueChangingFcn = createCallbackFcn(app, @TSliderProjTransValueChanging, true);
            app.TSliderProjTrans.MinorTicks = [];
            app.SLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.SLabel.Text = 'S';
            app.TSliderProjSagit = uislider(app.CTImageReconstructionPlatformUIFigure);
            app.TSliderProjSagit.MajorTicks = [];
            app.TSliderProjSagit.MajorTickLabels = {};
            app.TSliderProjSagit.ValueChangingFcn = createCallbackFcn(app, @TSliderProjSagitValueChanging, true);
            app.TSliderProjSagit.MinorTicks = [];
            app.CLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.CLabel.Text = 'C';
            app.TSliderProjCoron = uislider(app.CTImageReconstructionPlatformUIFigure);
            app.TSliderProjCoron.MajorTicks = [];
            app.TSliderProjCoron.MajorTickLabels = {};
            app.TSliderProjCoron.ValueChangingFcn = createCallbackFcn(app, @TSliderProjCoronValueChanging, true);
            app.TSliderProjCoron.MinorTicks = [];
            app.TSlider_4Label = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.TSlider_4Label.Text = 'T';
            app.TSliderImagTrans = uislider(app.CTImageReconstructionPlatformUIFigure);
            app.TSliderImagTrans.MajorTicks = [];
            app.TSliderImagTrans.MajorTickLabels = {};
            app.TSliderImagTrans.ValueChangingFcn = createCallbackFcn(app, @TSliderImagTransValueChanging, true);
            app.TSliderImagTrans.MinorTicks = [];
            app.SLabel_2 = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.SLabel_2.Text = 'S';
            app.TSliderImagSagit = uislider(app.CTImageReconstructionPlatformUIFigure);
            app.TSliderImagSagit.MajorTicks = [];
            app.TSliderImagSagit.MajorTickLabels = {};
            app.TSliderImagSagit.ValueChangingFcn = createCallbackFcn(app, @TSliderImagSagitValueChanging, true);
            app.TSliderImagSagit.MinorTicks = [];
            app.CLabel_2 = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.CLabel_2.Text = 'C';
            app.TSliderImagCoron = uislider(app.CTImageReconstructionPlatformUIFigure);
            app.TSliderImagCoron.MajorTicks = [];
            app.TSliderImagCoron.MajorTickLabels = {};
            app.TSliderImagCoron.ValueChangingFcn = createCallbackFcn(app, @TSliderImagCoronValueChanging, true);
            app.TSliderImagCoron.MinorTicks = [];
            app.ReconMethodDropDownLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.ReconMethodDropDownLabel.Text = 'Recon Method';
            app.ReconMethodDropDown = uidropdown(app.CTImageReconstructionPlatformUIFigure);
            app.ReconMethodDropDown.Items = {'OS-SART', 'Conjugate Gradient'};
            app.ReconMethodDropDown.Value = 'OS-SART';
            app.OrderedSubsetEditFieldLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.OrderedSubsetEditFieldLabel.Text = 'Ordered Subset';
            app.OrderedSubsetEditField = uieditfield(app.CTImageReconstructionPlatformUIFigure, 'numeric');
            app.OrderedSubsetEditField.Limits = [1 Inf];
            app.OrderedSubsetEditField.ValueDisplayFormat = '%.0f';
            app.OrderedSubsetEditField.Value = 20;
            app.MaximumIterEditFieldLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.MaximumIterEditFieldLabel.Text = 'Maximum Iter';
            app.MaximumIterEditField = uieditfield(app.CTImageReconstructionPlatformUIFigure, 'numeric');
            app.MaximumIterEditField.Limits = [1 Inf];
            app.MaximumIterEditField.ValueDisplayFormat = '%.0f';
            app.MaximumIterEditField.Value = 20;
            app.StartReconstructButton = uibutton(app.CTImageReconstructionPlatformUIFigure, 'push');
            app.StartReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @StartReconstructButtonPushed, true);
            app.StartReconstructButton.Text = 'Start Reconstruct';
            app.UIAxesScanTrajectory = uiaxes(app.CTImageReconstructionPlatformUIFigure);
            title(app.UIAxesScanTrajectory, 'Scanning Trajectory')
            xlabel(app.UIAxesScanTrajectory, 'X')
            ylabel(app.UIAxesScanTrajectory, 'Y')
            app.ThisMachineContainsGPU = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.ThisMachineContainsGPU.Text = 'This Machine Contains ';
            app.DModeSwitchLabel = uilabel(app.CTImageReconstructionPlatformUIFigure);
            app.DModeSwitchLabel.Text = '2D Mode';
            app.TwoDimModeSwitch = uiswitch(app.CTImageReconstructionPlatformUIFigure, 'slider');
            app.TwoDimModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TwoDimModeSwitchValueChanged, true);
            % Show the figure after all components are created
            app.CTImageReconstructionPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CTImageReconstructionPlatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CTImageReconstructionPlatformUIFigure)
        end
    end
end