classdef test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        HardwareDropDownLabel           matlab.ui.control.Label
        HardwareDropDown                matlab.ui.control.DropDown
        ScanningGeometryConfigurationPanel  matlab.ui.container.Panel
        SourcetocenterdistanceEditFieldLabel  matlab.ui.control.Label
        SourcetocenterdistanceEditField  matlab.ui.control.NumericEditField
        SourcetodetectordistanceEditFieldLabel  matlab.ui.control.Label
        SourcetodetectordistanceEditField  matlab.ui.control.NumericEditField
        DetectorResoUdirectionEditFieldLabel  matlab.ui.control.Label
        DetectorResoUdirectionEditField  matlab.ui.control.NumericEditField
        DetectorResoVdirectionEditFieldLabel  matlab.ui.control.Label
        DetectorResoVdirectionEditField  matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        SimulationPanel                 matlab.ui.container.Panel
        GenerateProjectionButton        matlab.ui.control.Button
        PhantomModelButtonGroup         matlab.ui.container.ButtonGroup
        AnalyticalModelButton           matlab.ui.control.RadioButton
        DiscreteModelButton             matlab.ui.control.RadioButton
        AnalyticalDiskModelButton       matlab.ui.control.RadioButton
        mmLabel                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        cmturnLabel                     matlab.ui.control.Label
        TotalProjectionNumberEditFieldLabel  matlab.ui.control.Label
        TotalProjectionNumberEditField  matlab.ui.control.NumericEditField
        ProjectionsPerTurnEditFieldLabel  matlab.ui.control.Label
        ProjectionsPerTurnEditField     matlab.ui.control.NumericEditField
        DetectorpixelsizeUEditFieldLabel  matlab.ui.control.Label
        DetectorpixelsizeUEditField     matlab.ui.control.NumericEditField
        DetectorpixelsizeVEditFieldLabel  matlab.ui.control.Label
        DetectorpixelsizeVEditField     matlab.ui.control.NumericEditField
        DetectorShapeDropDownLabel      matlab.ui.control.Label
        DetectorShapeDropDown           matlab.ui.control.DropDown
        DetectorCenterIndexUEditFieldLabel  matlab.ui.control.Label
        DetectorCenterIndexUEditField   matlab.ui.control.NumericEditField
        DetectorCenterIndexVEditFieldLabel  matlab.ui.control.Label
        DetectorCenterIndexVEditField   matlab.ui.control.NumericEditField
        ReconstructionconfigurationPanel  matlab.ui.container.Panel
        XResoEditFieldLabel             matlab.ui.control.Label
        XResoEditField                  matlab.ui.control.NumericEditField
        YResoEditFieldLabel             matlab.ui.control.Label
        YResoEditField                  matlab.ui.control.NumericEditField
        ZResoEditFieldLabel             matlab.ui.control.Label
        ZResoEditField                  matlab.ui.control.NumericEditField
        XpixelsizeEditFieldLabel        matlab.ui.control.Label
        XpixelsizeEditField             matlab.ui.control.NumericEditField
        YpixelsizeEditFieldLabel        matlab.ui.control.Label
        YpixelsizeEditField             matlab.ui.control.NumericEditField
        ZpixelsizeEditFieldLabel        matlab.ui.control.Label
        ZpixelsizeEditField             matlab.ui.control.NumericEditField
        cmLabel_5                       matlab.ui.control.Label
        cmLabel_6                       matlab.ui.control.Label
        cmLabel_7                       matlab.ui.control.Label
        CenterIndexXEditFieldLabel      matlab.ui.control.Label
        CenterIndexXEditField           matlab.ui.control.NumericEditField
        CenterIndexYEditFieldLabel      matlab.ui.control.Label
        CenterIndexYEditField           matlab.ui.control.NumericEditField
        CenterIndexZEditFieldLabel      matlab.ui.control.Label
        CenterIndexZEditField           matlab.ui.control.NumericEditField
        LoadProjectionButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        AnalyticalReconstructionTab     matlab.ui.container.Tab
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        OSSARTTab                       matlab.ui.container.Tab
        CGTab                           matlab.ui.container.Tab
        UIAxesProjTraversal             matlab.ui.control.UIAxes
        UIAxesProjSagittal              matlab.ui.control.UIAxes
        UIAxesProjCoronal               matlab.ui.control.UIAxes
        SaveReconResultsButton          matlab.ui.control.Button
        SaveAsImagesButton              matlab.ui.control.Button
        ProjMaxValueEditFieldLabel      matlab.ui.control.Label
        ProjMaxValueEditField           matlab.ui.control.NumericEditField
        ProjMinValueEditFieldLabel      matlab.ui.control.Label
        ProjMinValueEditField           matlab.ui.control.NumericEditField
        SliderProjTraverse              matlab.ui.control.Slider
        SliderProjSagittal              matlab.ui.control.Slider
        SliderProjCoronal               matlab.ui.control.Slider
        SaveProjectionButton            matlab.ui.control.Button
    end


    properties (Access = public)
        reconImage ReconImage % The reconstructed image
        projectionData ProjectionData
        scanGeometry ScanGeometry
    end

    methods (Access = private)    
        function setStartupDefaultFields(app)
            
            app.XResoEditField.Value = app.reconImage.getResoX();
            app.YResoEditField.Value = app.reconImage.getResoY();
            app.ZResoEditField.Value = app.reconImage.getResoZ();
            app.XpixelsizeEditField.Value = app.reconImage.getPixelSizeX();
            app.YpixelsizeEditField.Value = app.reconImage.getPixelSizeY();
            app.ZpixelsizeEditField.Value = app.reconImage.getPixelSizeZ();
            app.CenterIndexXEditField.Value = app.reconImage.getCtrIdxX();
            app.CenterIndexYEditField.Value = app.reconImage.getCtrIdxY();
            app.CenterIndexZEditField.Value = app.reconImage.getCtrIdxZ();
            
            app.SourcetocenterdistanceEditField.Value = app.scanGeometry.getSourceToCenterDistance();
            app.SourcetodetectordistanceEditField.Value = app.scanGeometry.getSourceToDetectorDistance();
            app.PitchEditField.Value = app.scanGeometry.getHelicalPitch();
            
            app.DetectorResoUdirectionEditField.Value = app.projectionData.getDetResoU();
            app.DetectorResoVdirectionEditField.Value = app.projectionData.getDetResoV();
            app.TotalProjectionNumberEditField.Value = app.projectionData.getTotalProjectionNumber();
            app.ProjectionsPerTurnEditField.Value = app.projectionData.getProjectionNumberPerTurn();
            
            set(app.ProjMaxValueEditField, 'Enable', 'off');
            set(app.ProjMinValueEditField, 'Enable', 'off');
        end        

        function gatheringTheInformationFromPanel(app)
            % Geometry
            app.scanGeometry.setSourceToCenterDistance(app.SourcetocenterdistanceEditField.Value);
            app.scanGeometry.setSourceToDetectorDistance(app.SourcetodetectordistanceEditField.Value);
            app.scanGeometry.setHelicalPitch(app.PitchEditField.Value);
            
            % Projection
            app.projectionData.setDetResoU(app.DetectorResoUdirectionEditField.Value);
            app.projectionData.setDetResoV(app.DetectorResoVdirectionEditField.Value);
            app.projectionData.setTotalProjectionNumber(app.TotalProjectionNumberEditField.Value);
            app.projectionData.setProjectionNumPerTurn(app.ProjectionsPerTurnEditField.Value);
            
            % Reconstruction Configuration
            app.reconImage.setResoX(app.XResoEditField.Value);
            app.reconImage.setResoY(app.YResoEditField.Value);
            app.reconImage.setResoZ(app.ZResoEditField.Value);
            app.reconImage.setPixelSizeX(app.XpixelsizeEditField.Value);
            app.reconImage.setPixelSizeY(app.YpixelsizeEditField.Value);
            app.reconImage.setPixelSizeZ(app.ZpixelsizeEditField.Value);
            app.reconImage.setCtrIdxX(app.CenterIndexXEditField.Value);
            app.reconImage.setCtrIdxY(app.CenterIndexYEditField.Value);
            app.reconImage.setCtrIdxZ(app.CenterIndexZEditField.Value);
            
            %app.reconImage.setPixelSizeZ(app.YpixelsizeEditField.Value);
        end
        
        function switchAllWidgets(app, status)
            set(app.SourcetocenterdistanceEditField, 'Enable', status);
            set(app.SourcetodetectordistanceEditField, 'Enable', status);
            set(app.DetectorResoUdirectionEditField, 'Enable', status);
            set(app.DetectorResoVdirectionEditField, 'Enable', status);
            set(app.PitchEditField, 'Enable', status);
            set(app.TotalProjectionNumberEditField, 'Enable', status);
            set(app.ProjectionsPerTurnEditField, 'Enable', status);
            
            set(app.LoadProjectionButton, 'Enable', status);
            set(app.StartButton,'Enable', status);
            
            set(app.XResoEditField, 'Enable', status);
            set(app.YResoEditField, 'Enable', status);
            set(app.ZResoEditField, 'Enable', status);
            set(app.XpixelsizeEditField, 'Enable', status);
            set(app.YpixelsizeEditField, 'Enable', status);
            set(app.ZpixelsizeEditField, 'Enable', status);
            set(app.CenterIndexXEditField, 'Enable', status);
            set(app.CenterIndexYEditField, 'Enable', status);
            set(app.CenterIndexZEditField, 'Enable', status);
            
            set(app.AlgorithmDropDown, 'Enable', status);
            
            set(app.SaveAsImagesButton, 'Enable', status);
            set(app.SaveReconResultsButton, 'Enable', status);
        end
        
        function createAnalyticalProjectionData(app, Phantom)
            PI = 3.14159265358979323846264;
            ProjBeginIndex = 1;
            ProjEndIndex = app.projectionData.getTotalProjectionNumber();
            ProjNumber = (ProjEndIndex - ProjBeginIndex + 1) / 2;
            ProjCenter= (ProjNumber + 1) / 2; % 
            ProjScale = app.projectionData.getProjectionNumberPerTurn(); % Number of projection per turn
            ScanR = app.scanGeometry.getSourceToCenterDistance();       % (cm) Scanning radius
            ObjR  = app.reconImage.getObjectRadius();       % (cm) Object radius
            
            delta    =2*acos(ObjR/ScanR);
            coef     =(2*PI-delta)/(2*PI*(1-cos(delta)));

            YL = app.projectionData.getDetResoU();        % sampling number per row
            ZL = app.projectionData.getDetResoV();         % sampling number per column
            HelicP =  app.scanGeometry.getHelicalPitch();      % (cm) Helical pitch
            U_HelicP = HelicP/ObjR;
            
            U_ScanR  = ScanR/ObjR;
            U_DW     = 2*(U_ScanR/(U_ScanR^2-1)^0.5)*YL/(YL-2);
            U_DH     = 2*coef*U_HelicP*ZL/(ZL-2);
            
            Proj = zeros(YL, ZL, floor(ProjNumber));
            hwait=waitbar(0,'Generating Projection.');
            app.switchAllWidgets('off');
            stp = 1 / (ProjEndIndex - ProjBeginIndex);
            for ProjIndex=ProjBeginIndex:ProjEndIndex
                THETA = (ProjIndex-ProjCenter)*2*PI/ProjScale;
                if (Phantom==1)
                    Slice = CreateProjSlice(THETA,U_ScanR,U_HelicP,YL,ZL,U_DW,U_DH);
                else
                    Slice = CreateProjSliceDisk(THETA,U_ScanR,U_HelicP,YL,ZL,U_DW,U_DH);
                end
                Slice = Slice*ObjR;
                SliceIndex =ProjIndex-ProjBeginIndex+1; 
                Proj(:,:,SliceIndex)= Slice;
                waitbar(stp*ProjIndex,hwait,'Generating Projection.');
            end
            close(hwait);
            app.projectionData.setProjection(Proj);
            msgbox('Analytical Phantom based projection generated','Analytical Phantom based projection generated');
            app.drawProjectionData();
            app.switchAllWidgets('on');
        end
        
        function drawProjectionData(app)
            Proj = app.projectionData.getProjection();
            [YYY, ZZZ, PPP] = size(Proj);
            imagesc(squeeze(Proj(:,:, floor(PPP/2))), 'Parent', app.UIAxesProjTraversal);
            imagesc(squeeze(Proj(:,floor(ZZZ/2), :)), 'Parent', app.UIAxesProjSagittal);
            imagesc(squeeze(Proj(floor(YYY/2),:, :)), 'Parent', app.UIAxesProjCoronal);
            
            app.ProjMaxValueEditField.Value = max(max(max(Proj)));
            app.ProjMinValueEditField.Value = min(min(min(Proj)));
            
            % Set slide
            app.SliderProjTraverse.Visible = true;
            app.SliderProjTraverse.Enable = true;
            app.SliderProjTraverse.Limits = [1, PPP];
            
            app.SliderProjSagittal.Visible = true;
            app.SliderProjSagittal.Enable = true;
            app.SliderProjSagittal.Limits = [1, ZZZ];
            
            app.SliderProjCoronal.Visible = true;
            app.SliderProjCoronal.Enable = true;
            app.SliderProjCoronal.Limits = [1, YYY];
        end
        
        function setProjectionDataFields(app)
            app.SourcetocenterdistanceEditField.Value = app.scanGeometry.getSourceToCenterDistance();
            app.SourcetodetectordistanceEditField.Value = app.scanGeometry.getSourceToDetectorDistance();
            app.DetectorResoUdirectionEditField.Value = app.projectionData.getDetResoU();
            app.DetectorResoVdirectionEditField.Value = app.projectionData.getDetResoV();
            app.DetectorCenterIndexUEditField.Value = app.projectionData.getDetCntIdxU();
            app.DetectorCenterIndexVEditField.Value = app.projectionData.getDetCntIdxV();
            app.DetectorpixelsizeUEditField.Value = app.projectionData.getDetSizeU();
            app.DetectorpixelsizeVEditField.Value = app.projectionData.getDetSizeV();
            if app.projectionData.getIsArcDetector()
                app.DetectorShapeDropDown.Value = 'Arc';
            else
                app.DetectorShapeDropDown.Value = 'Panel';
            end
            app.TotalProjectionNumberEditField.Value = app.projectionData.getTotalProjectionNumber();
            app.ProjectionsPerTurnEditField.Value = app.projectionData.getProjectionNumberPerTurn();
            
        end
        
    end

    methods (Access = public)
    
        function results = getProjectionData(app)
            results = app.projectionData;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            axis(app.UIAxes_2,'off');
            axis(app.UIAxes_3,'off');
            axis(app.UIAxesProjTraversal, 'off');
            axis(app.UIAxesProjSagittal, 'off');
            axis(app.UIAxesProjCoronal, 'off');
            app.reconImage = ReconImage;
            app.projectionData = ProjectionData;
            app.scanGeometry = ScanGeometry;
            
            % Set fields
            app.setStartupDefaultFields();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Gathering the information from the panel
            gatheringTheInformationFromPanel(app);
            % Start reconstruction
            app.switchAllWidgets('off');
            hwait=waitbar(0,'Reconstructing');
            set(app.StartButton, 'Enable','off');
            hardwareDropDownValue = app.HardwareDropDown.Value;
            useGPU = false;
            if strcmp(hardwareDropDownValue, 'CPU')
                useGPU = false;
            elseif strcmp(hardwareDropDownValue, 'GPU')
                useGPU = true;
            else
                useGPU = false;
            end
            c = katsevichReconForGUI(0.5, 0, app.scanGeometry, app.projectionData, app.reconImage, useGPU, hwait, app);
            app.reconImage.setImage(c);
            
            
            % After reconstruction
            close(hwait);
            
            imagesc(squeeze(c(:,floor(app.reconImage.getResoY()/2),:)),'Parent', app.UIAxes_2);
            imagesc(squeeze(c(floor(app.reconImage.getResoX()/2), :,:)),'Parent', app.UIAxes_3);
            imagesc(squeeze(c(:,:,floor(app.reconImage.getResoZ()/2))),'Parent', app.UIAxes);
            app.switchAllWidgets('on');
        end

        % Button pushed function: LoadProjectionButton
        function LoadProjectionButtonPushed(app, event)
            [file, path] = uigetfile;
            fileName = [path,file];
            app.projectionData.ReadFile(fileName);
            app.setProjectionDataFields();
            app.drawProjectionData();

        end

        % Button pushed function: SaveReconResultsButton
        function SaveReconResultsButtonPushed(app, event)
            filter = {'*.reconImg'};
            [file, path] = uiputfile(filter);
            fileName = [path, file];
            app.reconImage.WriteFile(fileName);
            msgbox('Reconstructed Image saved', 'Image saved');
        end

        % Value changing function: SliderProjTraverse
        function SliderProjTraverseValueChanging(app, event)
            changingValue = event.Value;
            Proj = app.projectionData.getProjection();
            imagesc(squeeze(Proj(:,:, floor(changingValue))), 'Parent', app.UIAxesProjTraversal);
        end

        % Value changed function: SliderProjTraverse
        function SliderProjTraverseValueChanged(app, event)
            value = app.SliderProjTraverse.Value;
            
        end

        % Value changing function: SliderProjSagittal
        function SliderProjSagittalValueChanging(app, event)
            changingValue = event.Value;
            Proj = app.projectionData.getProjection();
            imagesc(squeeze(Proj(:,floor(changingValue), :)), 'Parent', app.UIAxesProjSagittal);
        end

        % Value changing function: SliderProjCoronal
        function SliderProjCoronalValueChanging(app, event)
            changingValue = event.Value;
            Proj = app.projectionData.getProjection();
            imagesc(squeeze(Proj(floor(changingValue), :, :)), 'Parent', app.UIAxesProjCoronal);
        end

        % Button pushed function: GenerateProjectionButton
        function GenerateProjectionButtonPushed(app, event)
            if(app.AnalyticalModelButton.Value)
                app.createAnalyticalProjectionData(1);
            elseif(app.AnalyticalDiskModelButton.Value)
                app.createAnalyticalProjectionData(0);                
            else
                errordlg('Sorry, We do not support this yet!', 'Unsupported');
            end
        end

        % Button pushed function: SaveProjectionButton
        function SaveProjectionButtonPushed(app, event)
            filter = {'*.prj'};
            [file, path] = uiputfile(filter);
            fileName = [path, file];
            app.projectionData.WriteFile(fileName);
            msgbox('Projection Data saved', 'Projection saved');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.HardwareDropDownLabel = uilabel(app.UIFigure);
            app.HardwareDropDownLabel.Text = 'Hardware';
            app.HardwareDropDown = uidropdown(app.UIFigure);
            app.HardwareDropDown.Items = {'CPU', 'CPU multi cores', 'GPU', 'multi GPUs'};
            app.HardwareDropDown.Value = 'GPU';
            app.ScanningGeometryConfigurationPanel = uipanel(app.UIFigure);
            app.ScanningGeometryConfigurationPanel.Title = 'Scanning Geometry Configuration';
            app.SourcetocenterdistanceEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.SourcetocenterdistanceEditFieldLabel.Text = 'Source to center distance';
            app.SourcetocenterdistanceEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.SourcetocenterdistanceEditField.Value = 541;
            app.SourcetodetectordistanceEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.SourcetodetectordistanceEditFieldLabel.Text = 'Source to detector distance';
            app.SourcetodetectordistanceEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.SourcetodetectordistanceEditField.Value = 949;
            app.DetectorResoUdirectionEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.DetectorResoUdirectionEditFieldLabel.Text = 'Detector Reso U direction';
            app.DetectorResoUdirectionEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.DetectorResoUdirectionEditField.Limits = [1 Inf];
            app.DetectorResoUdirectionEditField.ValueDisplayFormat = '%.0f';
            app.DetectorResoUdirectionEditField.Value = 888;
            app.DetectorResoVdirectionEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.DetectorResoVdirectionEditFieldLabel.Text = 'Detector Reso V direction';
            app.DetectorResoVdirectionEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.DetectorResoVdirectionEditField.Limits = [0 Inf];
            app.DetectorResoVdirectionEditField.ValueDisplayFormat = '%.0f';
            app.DetectorResoVdirectionEditField.Value = 64;
            app.PitchEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.PitchEditField.Limits = [0 Inf];
            app.SimulationPanel = uipanel(app.ScanningGeometryConfigurationPanel);
            app.SimulationPanel.Title = 'Simulation';
            app.GenerateProjectionButton = uibutton(app.SimulationPanel, 'push');
            app.GenerateProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateProjectionButtonPushed, true);
            app.GenerateProjectionButton.Text = 'Generate Projection';
            app.PhantomModelButtonGroup = uibuttongroup(app.SimulationPanel);
            app.PhantomModelButtonGroup.Title = 'Phantom Model';
            app.AnalyticalModelButton = uiradiobutton(app.PhantomModelButtonGroup);
            app.AnalyticalModelButton.Text = 'Analytical Model';
            app.AnalyticalModelButton.Value = true;
            app.DiscreteModelButton = uiradiobutton(app.PhantomModelButtonGroup);
            app.DiscreteModelButton.Text = 'Discrete Model';
            app.AnalyticalDiskModelButton = uiradiobutton(app.PhantomModelButtonGroup);
            app.AnalyticalDiskModelButton.Text = 'Analytical Disk Model';
            app.mmLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.ScanningGeometryConfigurationPanel);
            app.mmLabel_2.Text = 'mm';
            app.cmturnLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.cmturnLabel.Text = 'cm/turn';
            app.TotalProjectionNumberEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.TotalProjectionNumberEditFieldLabel.Text = 'Total Projection Number';
            app.TotalProjectionNumberEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.TotalProjectionNumberEditField.Limits = [0 Inf];
            app.TotalProjectionNumberEditField.Value = 984;
            app.ProjectionsPerTurnEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.ProjectionsPerTurnEditFieldLabel.Text = 'Projections Per Turn';
            app.ProjectionsPerTurnEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.ProjectionsPerTurnEditField.Limits = [0 Inf];
            app.ProjectionsPerTurnEditField.Value = 984;
            app.DetectorpixelsizeUEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.DetectorpixelsizeUEditFieldLabel.Text = 'Detector pixel size U';
            app.DetectorpixelsizeUEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.DetectorpixelsizeUEditField.Limits = [0 Inf];
            app.DetectorpixelsizeUEditField.Value = 1.0239;
            app.DetectorpixelsizeVEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.DetectorpixelsizeVEditFieldLabel.Text = 'Detector pixel size V';
            app.DetectorpixelsizeVEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.DetectorpixelsizeVEditField.Limits = [0 Inf];
            app.DetectorpixelsizeVEditField.Value = 1.096439;
            app.DetectorShapeDropDownLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.DetectorShapeDropDownLabel.Text = 'Detector Shape';
            app.DetectorShapeDropDown = uidropdown(app.ScanningGeometryConfigurationPanel);
            app.DetectorShapeDropDown.Items = {'Arc', 'Panel'};
            app.DetectorShapeDropDown.Value = 'Arc';
            app.DetectorCenterIndexUEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.DetectorCenterIndexUEditFieldLabel.Text = 'Detector Center Index U';
            app.DetectorCenterIndexUEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.DetectorCenterIndexUEditField.Limits = [0 Inf];
            app.DetectorCenterIndexUEditField.Value = 444.75;
            app.DetectorCenterIndexVEditFieldLabel = uilabel(app.ScanningGeometryConfigurationPanel);
            app.DetectorCenterIndexVEditFieldLabel.Text = 'Detector Center Index V';
            app.DetectorCenterIndexVEditField = uieditfield(app.ScanningGeometryConfigurationPanel, 'numeric');
            app.DetectorCenterIndexVEditField.Limits = [0 Inf];
            app.DetectorCenterIndexVEditField.Value = 31.5;
            app.ReconstructionconfigurationPanel = uipanel(app.UIFigure);
            app.ReconstructionconfigurationPanel.Title = 'Reconstruction configuration';
            app.XResoEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.XResoEditFieldLabel.Text = 'X Reso';
            app.XResoEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.XResoEditField.Limits = [1 1024];
            app.XResoEditField.ValueDisplayFormat = '%.0f';
            app.XResoEditField.Value = 256;
            app.YResoEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.YResoEditFieldLabel.Text = 'Y Reso';
            app.YResoEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.YResoEditField.Limits = [1 1024];
            app.YResoEditField.ValueDisplayFormat = '%.0f';
            app.YResoEditField.Value = 256;
            app.ZResoEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.ZResoEditFieldLabel.Text = 'Z Reso';
            app.ZResoEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.ZResoEditField.Limits = [1 1024];
            app.ZResoEditField.RoundFractionalValues = 'on';
            app.ZResoEditField.ValueDisplayFormat = '%.0f';
            app.ZResoEditField.Value = 256;
            app.XpixelsizeEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.XpixelsizeEditFieldLabel.Text = 'X pixel size';
            app.XpixelsizeEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.XpixelsizeEditField.Limits = [0 Inf];
            app.XpixelsizeEditField.Value = 1;
            app.YpixelsizeEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.YpixelsizeEditFieldLabel.Text = 'Y pixel size';
            app.YpixelsizeEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.YpixelsizeEditField.Limits = [0 Inf];
            app.YpixelsizeEditField.Value = 1;
            app.ZpixelsizeEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.ZpixelsizeEditFieldLabel.Text = 'Z pixel size';
            app.ZpixelsizeEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.ZpixelsizeEditField.Limits = [0 Inf];
            app.ZpixelsizeEditField.Value = 1;
            app.cmLabel_5 = uilabel(app.ReconstructionconfigurationPanel);
            app.cmLabel_5.Text = 'cm';
            app.cmLabel_6 = uilabel(app.ReconstructionconfigurationPanel);
            app.cmLabel_6.Text = 'cm';
            app.cmLabel_7 = uilabel(app.ReconstructionconfigurationPanel);
            app.cmLabel_7.Text = 'cm';
            app.CenterIndexXEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.CenterIndexXEditFieldLabel.Text = 'Center Index X';
            app.CenterIndexXEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.CenterIndexYEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.CenterIndexYEditFieldLabel.Text = 'Center Index Y';
            app.CenterIndexYEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.CenterIndexZEditFieldLabel = uilabel(app.ReconstructionconfigurationPanel);
            app.CenterIndexZEditFieldLabel.Text = 'Center Index Z';
            app.CenterIndexZEditField = uieditfield(app.ReconstructionconfigurationPanel, 'numeric');
            app.LoadProjectionButton = uibutton(app.UIFigure, 'push');
            app.LoadProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectionButtonPushed, true);
            app.LoadProjectionButton.Text = 'Load Projection';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalyticalReconstructionTab = uitab(app.TabGroup);
            app.AnalyticalReconstructionTab.Title = 'Analytical Reconstruction';
            app.AlgorithmDropDownLabel = uilabel(app.AnalyticalReconstructionTab);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.AnalyticalReconstructionTab);
            app.AlgorithmDropDown.Items = {'Katsevich Algorithm'};
            app.AlgorithmDropDown.Value = 'Katsevich Algorithm';
            app.OSSARTTab = uitab(app.TabGroup);
            app.OSSARTTab.Title = 'OS-SART';
            app.CGTab = uitab(app.TabGroup);
            app.CGTab.Title = 'CG';
            app.UIAxesProjTraversal = uiaxes(app.UIFigure);
            app.UIAxesProjSagittal = uiaxes(app.UIFigure);
            app.UIAxesProjCoronal = uiaxes(app.UIFigure);
            app.SaveReconResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveReconResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReconResultsButtonPushed, true);
            app.SaveReconResultsButton.Text = 'Save Recon Results';
            app.SaveAsImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveAsImagesButton.Text = 'Save As Images';
            app.ProjMaxValueEditFieldLabel = uilabel(app.UIFigure);
            app.ProjMaxValueEditFieldLabel.Text = 'Proj Max Value';
            app.ProjMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjMaxValueEditField.Value = Inf;
            app.ProjMinValueEditFieldLabel = uilabel(app.UIFigure);
            app.ProjMinValueEditFieldLabel.Text = 'Proj Min Value';
            app.ProjMinValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjMinValueEditField.Value = -Inf;
            app.SliderProjTraverse = uislider(app.UIFigure);
            app.SliderProjTraverse.Limits = [0 1];
            app.SliderProjTraverse.MajorTicks = [];
            app.SliderProjTraverse.MajorTickLabels = {};
            app.SliderProjTraverse.ValueChangedFcn = createCallbackFcn(app, @SliderProjTraverseValueChanged, true);
            app.SliderProjTraverse.ValueChangingFcn = createCallbackFcn(app, @SliderProjTraverseValueChanging, true);
            app.SliderProjTraverse.MinorTicks = [];
            app.SliderProjTraverse.Enable = 'off';
            app.SliderProjSagittal = uislider(app.UIFigure);
            app.SliderProjSagittal.Limits = [0 1];
            app.SliderProjSagittal.MajorTicks = [];
            app.SliderProjSagittal.MajorTickLabels = {};
            app.SliderProjSagittal.ValueChangingFcn = createCallbackFcn(app, @SliderProjSagittalValueChanging, true);
            app.SliderProjSagittal.MinorTicks = [];
            app.SliderProjSagittal.Enable = 'off';
            app.SliderProjCoronal = uislider(app.UIFigure);
            app.SliderProjCoronal.Limits = [0 1];
            app.SliderProjCoronal.MajorTicks = [];
            app.SliderProjCoronal.MajorTickLabels = {};
            app.SliderProjCoronal.ValueChangingFcn = createCallbackFcn(app, @SliderProjCoronalValueChanging, true);
            app.SliderProjCoronal.MinorTicks = [];
            app.SliderProjCoronal.Enable = 'off';
            app.SaveProjectionButton = uibutton(app.UIFigure, 'push');
            app.SaveProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectionButtonPushed, true);
            app.SaveProjectionButton.Text = 'Save Projection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end